classdef ListenandgenerateSpec < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure             matlab.ui.Figure
        PresstolistenButton  matlab.ui.control.Button
        UIAxes               matlab.ui.control.UIAxes
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: PresstolistenButton
        function PresstolistenButtonPushed(app, event)
           
    recObj = audiorecorder;  %initialize recording object
    disp('Start recording.')
    recordblocking(recObj, 10); %start of recording
    disp('End of Recording.');
    y = getaudiodata(recObj); %get data from audio
    filename = 'recording.wav';
    audiowrite(filename,y,44100); %write a 44.1 MHz sampled file of the audio
    [y,Fs] = audioread('recording.wav');
    window=hamming(512); %%window with size of 512 points
    noverlap=256; %%the number of points for repeating the window
    nfft=1024; %%size of the fADS = audioDatastore(folder)it
    [S,F,T,P] = spectrogram(y,window,noverlap,nfft,Fs,'yaxis');
    surf(T,F,10*log10(P),'edgecolor','none'); axis tight;view(0,90);
    colormap(hot); %%for the indexed colors, check this in help for blck/white
    set(gca,'clim',[-80 -30]); %%clim is the limits of the axis colours
    xlabel('Time s');
    ylabel('Frequency kHz')
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Generated Spectrogram from 30sec of Listening')
            xlabel(app.UIAxes, 'Time (sec)')
            ylabel(app.UIAxes, 'Frequency (Hz)')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes.XTick = [0 0.2 0.4 0.6 0.8 1 1.2];
            app.UIAxes.XTickLabel = {'0'; '5'; '10'; '15'; '20'; '25'; '30'};
            app.UIAxes.YTickLabel = {'0'; '2'; '4'; '6'; '08'; '10'};
            app.PresstolistenButton = uibutton(app.UIFigure, 'push');
            app.PresstolistenButton.ButtonPushedFcn = createCallbackFcn(app, @PresstolistenButtonPushed, true);
            app.PresstolistenButton.Text = 'Press to listen';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ListenandgenerateSpec

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end